classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        nbGenerationsSpinner            matlab.ui.control.Spinner
        nbGenerationsSpinnerLabel       matlab.ui.control.Label
        nbPopulationsSpinner            matlab.ui.control.Spinner
        nbPopulationsSpinnerLabel       matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ProbabilityofcrossoverEditField  matlab.ui.control.EditField
        ProbabilityofcrossoverEditFieldLabel  matlab.ui.control.Label
        ProbabilityofmutationEditField  matlab.ui.control.EditField
        ProbabilityofmutationEditFieldLabel  matlab.ui.control.Label
        fx1x2EditField                  matlab.ui.control.EditField
        fx1x2EditFieldLabel             matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            close all;
            clc;
            mu=app.ProbabilityofmutationEditField.Value;
            Pc=app.ProbabilityofcrossoverEditField.Value;
            N= app.nbPopulationsSpinner.Value;
            gnMax= app.nbGenerationsSpinner.Value;
            eqn= app.fx1x2EditField.Value;
            fileID = fopen('./func.txt','wt');  
            fprintf(fileID,'@(x1,x2)');
            fprintf(fileID,eqn);
            fclose(fileID);
            [a, b, c, d] = G_Algo(Pc, mu, N, gnMax, eqn)
            x = [1:gnMax];
            plot(app.UIAxes, x,a(:,1),'b--o',x,a(:,2),'r--o');
            plot(app.UIAxes5, x,b,'b--o');
            plot(app.UIAxes2, x,c,'b--o');
            plot(app.UIAxes4, x,d,'b--o');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Number of generations')
            ylabel(app.UIAxes, 'OPTIMUM SOLUTION')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Number of generations')
            ylabel(app.UIAxes2, 'Minimum Fitness Value')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'NO. OF GENERATIONS')
            ylabel(app.UIAxes4, 'Maximum Fitness Value')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes5, 'NO. OF GENERATIONS')
            ylabel(app.UIAxes5, 'Average Fitness')
            zlabel(app.UIAxes5, 'Z')
            app.fx1x2EditFieldLabel = uilabel(app.UIFigure);
            app.fx1x2EditFieldLabel.Text = 'f(x1, x2)=';
            app.fx1x2EditField = uieditfield(app.UIFigure, 'text');
            app.ProbabilityofmutationEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityofmutationEditFieldLabel.Text = 'Probability of  mutation';
            app.ProbabilityofmutationEditField = uieditfield(app.UIFigure, 'text');
            app.ProbabilityofcrossoverEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityofcrossoverEditFieldLabel.Text = 'Probability of crossover';
            app.ProbabilityofcrossoverEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.nbPopulationsSpinnerLabel = uilabel(app.UIFigure);
            app.nbPopulationsSpinnerLabel.Text = 'nb Populations';
            app.nbPopulationsSpinner = uispinner(app.UIFigure);
            app.nbGenerationsSpinnerLabel = uilabel(app.UIFigure);
            app.nbGenerationsSpinnerLabel.Text = 'nb Generations';
            app.nbGenerationsSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end